classdef test_angle < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        Load_image_folderButton   matlab.ui.control.Button
        image_folderEditField     matlab.ui.control.EditField
        contrastEditFieldLabel    matlab.ui.control.Label
        contrast                  matlab.ui.control.NumericEditField
        previous_stackButton      matlab.ui.control.Button
        next_stackButton          matlab.ui.control.Button
        previous_z_posButton      matlab.ui.control.Button
        next_z_posButton          matlab.ui.control.Button
        image_nameEditFieldLabel  matlab.ui.control.Label
        image_nameEditField       matlab.ui.control.EditField
        AngleData                 matlab.ui.control.Table
        calculate_angleButton     matlab.ui.control.Button
        yawEditFieldLabel         matlab.ui.control.Label
        yawEditField              matlab.ui.control.NumericEditField
        rollEditFieldLabel        matlab.ui.control.Label
        rollEditField             matlab.ui.control.NumericEditField
        save_angleButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        midindex_imagefile; % Description
        image_z_stack;
        image_index;
        image_z_index;
        current_image
        current_image_name;
        image_name;
        angle_data;
        defocus_matrix;
        image_stack;
        k;
    end
    
    methods (Access = private)
        
        function results = updateimage(app)
            if app.image_index > length(app.midindex_imagefile)
                app.image_index = length(app.midindex_imagefile);
                return;
            elseif app.image_index < 1
                app.image_index = 1;
                return;
            end
            if app.image_z_index > 51
                app.image_z_index = 51;
            elseif app.image_z_index < 1
                app.image_z_index = 1;
            end
            
            image_name = app.midindex_imagefile(app.image_index).name;
            app.k = strfind(image_name,'_');
            new_image_name = [image_name(1:app.k(end)),num2str(app.image_z_index),'.tiff'];
%             
%             app.current_image = imread([app.midindex_imagefile(app.image_index).folder,'/',new_image_name]);
            %app.current_image = app.current_image(1024-511:1024+512,1024-511:1024+512);
            app.current_image = app.image_stack{app.image_z_index};
            app.current_image_name = new_image_name;
            
            app.UIAxes.Visible = 'off';
            imshow(app.current_image,[0,app.contrast.Value],'Parent',app.UIAxes);
            %app.UIAxes.Interactions = [];
            app.UIAxes.Title.String = [num2str(app.image_index),'/',num2str(length(app.midindex_imagefile))];
            
            drawgrid(app);
            app.image_nameEditField.Value = new_image_name;
        end
        
        function results = drawgrid(app)
%             x = [1,2048];
%             y = [1024,1024];
            %x = 128:128:2048;
            for x = 128:128:1024
                line([1,1024],[x,x],'Color','green','LineStyle','-','Parent',app.UIAxes);
                line([x,x],[1,1024],'Color','green','LineStyle','-','Parent',app.UIAxes)
            end
            %line(x,y,'Color','green','LineStyle','-','Parent',app.UIAxes);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.angle_data = readtable('test_angle_Datasheet.txt');
            app.AngleData.Data = app.angle_data;
        end

        % Button pushed function: Load_image_folderButton
        function Load_image_folderButtonPushed(app, event)
            file_path = uigetdir('F:/');
            app.image_folderEditField.Value = file_path;
            app.midindex_imagefile = dir([file_path,'\*_26.tiff']);
            if length(app.midindex_imagefile) == 0
                f = msgbox('no image in current folder','warning');
                return;
            end
            app.image_folderEditField.Value = file_path;
            app.image_index = 1;
            app.image_z_index = 26;
            %app.contrast_value = 2000;
            app.contrast.Value = 2000;
            updateimage(app);
            %drawgrid(app);
            %disp(length(app.midindex_imagefile))
        end

        % Value changed function: contrast
        function contrastValueChanged(app, event)
            value = app.contrast.Value;
            updateimage(app);
        end

        % Button pushed function: previous_stackButton
        function previous_stackButtonPushed(app, event)
            app.image_index = app.image_index - 1;
            app.image_z_index = 26;
            if app.image_index > length(app.midindex_imagefile)
                app.image_index = length(app.midindex_imagefile);
                return;
            elseif app.image_index < 1
                app.image_index = 1;
                return;
            end
            
            
            image_name = app.midindex_imagefile(app.image_index).name;
            app.k = strfind(image_name,'_');
            for index = 1:51
                new_image_name = [image_name(1:app.k(end)),num2str(index),'.tiff'];
                app.image_stack{index} = imread([app.midindex_imagefile(app.image_index).folder,'/',new_image_name]); 
            end
            
            updateimage(app);
            app.angle_data = readtable('test_angle_Datasheet.txt');
            app.AngleData.Data = app.angle_data;
        end

        % Button pushed function: next_stackButton
        function next_stackButtonPushed(app, event)
            app.image_index = app.image_index + 1;
            app.image_z_index = 26;
            if app.image_index > length(app.midindex_imagefile)
                app.image_index = length(app.midindex_imagefile);
                return;
            elseif app.image_index < 1
                app.image_index = 1;
                return;
            end
            
            
            image_name = app.midindex_imagefile(app.image_index).name;
            app.k = strfind(image_name,'_');
            for index = 1:51
                new_image_name = [image_name(1:app.k(end)),num2str(index),'.tiff'];
                app.image_stack{index} = imread([app.midindex_imagefile(app.image_index).folder,'/',new_image_name]); 
            end
            updateimage(app);
            app.angle_data = readtable('test_angle_Datasheet.txt');
            app.AngleData.Data = app.angle_data;
        end

        % Button pushed function: previous_z_posButton
        function previous_z_posButtonPushed(app, event)
            app.image_z_index = app.image_z_index - 2;
            if app.image_z_index > 51
                app.image_z_index = 51;
            elseif app.image_z_index < 1
                app.image_z_index = 1;
            end
            updateimage(app);
        end

        % Button pushed function: next_z_posButton
        function next_z_posButtonPushed(app, event)
            app.image_z_index = app.image_z_index + 2;
            if app.image_z_index > 51
                app.image_z_index = 51;
            elseif app.image_z_index < 1
                app.image_z_index = 1;
            end
            updateimage(app);
        end

        % Button pushed function: calculate_angleButton
        function calculate_angleButtonPushed(app, event)
            matrix_defocus = zeros(8,8);
            for i = 1:8
                for j = 1:8
                    matrix_defocus(i,j) = double(table2array(app.AngleData.Data(i,j)));
                end
            end
            matrix_defocus_new = zeros(8,8);
            for i = 1:8
                for j=1:8
                    if matrix_defocus(i,j) == 100
                        matrix_defocus_new(i,j) = 100;
                    elseif abs(matrix_defocus(i,j)) > 100
                        disp('wrong number.');
                        return;
                    else
                        matrix_defocus_new(i,j) = (matrix_defocus(i,j) - 26)*2;
                    end   
                end
            end
            actual_defocus = matrix_defocus_new;
            app.defocus_matrix = matrix_defocus_new;
            x=[];y=[];z=[];
            % fit plane
            for i = 1:8
                for j = 1:8
                    if actual_defocus(i,j) == 100
                        continue;
                    else
                        x = [x,64 + (i-1)*128];
                        y = [y,64 + (j-1)*128];
                        z = [z,actual_defocus(i,j)];
                    end
                end
            end
            [a,num_points] = size(x);
            X = [ones(num_points,1),x',y'];
            b = regress(z',X);
            % roll angle
            z1 = b(1) + b(2)*1 + b(3)*512;
            z2 = b(1) + b(2)*1024 + b(3)*512;
            roll_angle = atan((z2-z1)/(1024-1)*0.65)/pi*180;
            % yaw angle
            z1 = b(1) + b(2)*512 + b(3)*1;
            z2 = b(1) + b(2)*512 + b(3)*1024;
            yaw_angle = atan((z2-z1)/(1024*0.65))/pi*180;
            app.yawEditField.Value = yaw_angle;
            app.rollEditField.Value = roll_angle;
        end

        % Button pushed function: save_angleButton
        function save_angleButtonPushed(app, event)
            fp = fopen('angle_true.txt','a');
            fprintf(fp,[app.current_image_name(1:6),'\n']);
            fprintf(fp,['actual_yaw: ',num2str(app.yawEditField.Value),' actual_roll: ',num2str(app.rollEditField.Value),'\n'])
            fclose(fp);
            
%             fp = fopen('angle_ture_matrix','a');
%             fprintf(fp,[app.current_image_name(1:6),'\n']);
%             
%             fclose(fp);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.Load_image_folderButton = uibutton(app.UIFigure, 'push');
            app.Load_image_folderButton.ButtonPushedFcn = createCallbackFcn(app, @Load_image_folderButtonPushed, true);
            app.Load_image_folderButton.Text = 'Load_image_folder';
            app.image_folderEditField = uieditfield(app.UIFigure, 'text');
            app.contrastEditFieldLabel = uilabel(app.UIFigure);
            app.contrastEditFieldLabel.Text = 'contrast';
            app.contrast = uieditfield(app.UIFigure, 'numeric');
            app.contrast.ValueChangedFcn = createCallbackFcn(app, @contrastValueChanged, true);
            app.contrast.Value = 2000;
            app.previous_stackButton = uibutton(app.UIFigure, 'push');
            app.previous_stackButton.ButtonPushedFcn = createCallbackFcn(app, @previous_stackButtonPushed, true);
            app.previous_stackButton.Text = 'previous_stack';
            app.next_stackButton = uibutton(app.UIFigure, 'push');
            app.next_stackButton.ButtonPushedFcn = createCallbackFcn(app, @next_stackButtonPushed, true);
            app.next_stackButton.Text = 'next_stack';
            app.previous_z_posButton = uibutton(app.UIFigure, 'push');
            app.previous_z_posButton.ButtonPushedFcn = createCallbackFcn(app, @previous_z_posButtonPushed, true);
            app.previous_z_posButton.Text = 'previous_z_pos';
            app.next_z_posButton = uibutton(app.UIFigure, 'push');
            app.next_z_posButton.ButtonPushedFcn = createCallbackFcn(app, @next_z_posButtonPushed, true);
            app.next_z_posButton.Text = 'next_z_pos';
            app.image_nameEditFieldLabel = uilabel(app.UIFigure);
            app.image_nameEditFieldLabel.Text = 'image_name';
            app.image_nameEditField = uieditfield(app.UIFigure, 'text');
            app.AngleData = uitable(app.UIFigure);
            app.AngleData.ColumnName = {'c1'; 'c2'; 'c3'; 'c4'; 'c5'; 'c6'; 'c7'; 'c8'};
            app.AngleData.RowName = {};
            app.AngleData.ColumnEditable = true;
            app.calculate_angleButton = uibutton(app.UIFigure, 'push');
            app.calculate_angleButton.ButtonPushedFcn = createCallbackFcn(app, @calculate_angleButtonPushed, true);
            app.calculate_angleButton.Text = 'calculate_angle';
            app.yawEditFieldLabel = uilabel(app.UIFigure);
            app.yawEditFieldLabel.Text = 'yaw';
            app.yawEditField = uieditfield(app.UIFigure, 'numeric');
            app.rollEditFieldLabel = uilabel(app.UIFigure);
            app.rollEditFieldLabel.Text = 'roll';
            app.rollEditField = uieditfield(app.UIFigure, 'numeric');
            app.save_angleButton = uibutton(app.UIFigure, 'push');
            app.save_angleButton.ButtonPushedFcn = createCallbackFcn(app, @save_angleButtonPushed, true);
            app.save_angleButton.Text = 'save_angle';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test_angle

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end